classdef OszilloskopGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        GridLayout                  matlab.ui.container.GridLayout
        LeftPanel                   matlab.ui.container.Panel
        FouriertransformationLabel  matlab.ui.control.Label
        fftButton                   matlab.ui.control.Button
        exportdatatoworkspaceLabel  matlab.ui.control.Label
        saveButton                  matlab.ui.control.Button
        startButton                 matlab.ui.control.Button
        NumberofChannelsEditField   matlab.ui.control.NumericEditField
        NumberofChannelsLabel       matlab.ui.control.Label
        stopButton                  matlab.ui.control.Button
        connectedLamp               matlab.ui.control.Lamp
        connectedLampLabel          matlab.ui.control.Label
        ConnectButton               matlab.ui.control.Button
        RightPanel                  matlab.ui.container.Panel
        UIAxes                      matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        noChan=1; % Description
        DS1000z=[];
        sampRate=0;
        oscRAWData=[];
        oscRAWLen=[];
        oscData=[];
        oscDataLen=[]
        oscDataVoltageScale=[];
        oscDataTime=[];
        startStreamData=0;
    end
    
    methods (Access = private)
        
        function getOscidata(app,channel)
            for i=1:channel
                
                % Set channel
%                 pause(0.01)
                fprintf(app.DS1000z, [':WAV:SOUR CHAN' num2str(i)] );
%                 pause(0.01)
                % Channel?
                fprintf(app.DS1000z, ':WAV:SOUR?' );
%                 pause(0.01)
                % disp(['Aktive: ' fscanf(app.DS1000z)])
                
                
%                 pause(0.01)
                % Read the waveform data
                fprintf(app.DS1000z, ':wav:data?' );
                pause(0.1)
                % Request the data
                [app.oscRAWData(:,i), app.oscRAWLen]= fread(app.DS1000z, app.DS1000z.InputBufferSize);
                % disp(['daten gelesen: ' num2str(app.oscRAWLen)])
                
                % Request the y-Axis scale
%                 pause(0.01)
                fprintf(app.DS1000z, ':WAV:YINC?' );
%                 pause(0.01)
                [app.oscDataVoltageScale(:,i)]= str2double(fscanf(app.DS1000z));
                
            end
            
            % Samplerate T?
%             pause(0.01)
            fprintf(app.DS1000z, ':WAV:XINC?');
%             pause(0.01)
            app.sampRate = str2double(fscanf(app.DS1000z));
            
            % cut the header data
            app.oscData=app.oscRAWData(12:app.oscRAWLen-1,:); % 0-11 -> Data header
            app.oscDataLen=size(app.oscData,1);               % 0-11 -> Data header
            
            % format data as voltage
            app.oscData=app.oscData.*app.oscDataVoltageScale;
            
            
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            instrreset;  % disconnects VISA objects
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {199, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Button pushed function: ConnectButton
        function ConnectButtonPushed(app, event)
            % NOT WORKING!  Find a VISA-USB object.
            %             if isempty(app.DS1000z)
            %                 % app.DS1000z = instrfind('Type', 'visa-usb', 'RsrcName', 'USB0::0x1AB1::0x04CE::DS1ZA192712385::0::INSTR', 'Tag', '');
            %                  app.DS1000z = instrfind('Type', 'visa-usb');
            %                  try
            %                  deviceNr=app.DS1000z.SerialNumber;
            %                  catch
            %                      error('Cannot find VISA USB device.')
            %                  end
            %
            %             end
            
            % Since instrfind is not finding any device - workaround -
            o=oscilloscope();
            devStr=o.resources;
            deviceNr=devStr{contains(devStr,'USB')};
            clear o;
            
            
            % Create the VISA-USB object if it does not exist
            % otherwise use the object that was found.
            if isempty(app.DS1000z)
                app.DS1000z = visa('NI',deviceNr);
                %app.DS1000z = visa('NI','USB0::0x1AB1::0x04CE::DS1ZA192712515::0::INSTR');
                %app.DS1000z = visa('NI', 'USB0::0x1AB1::0x04CE::DS1ZA192712385::0::INSTR');
            else
                fclose(app.DS1000z);
                app.DS1000z=app.DS1000z(1);
            end
            app.DS1000z.InputBufferSize = 2048;
            
            
            % Connect to instrument object, obj1.
            if ~strcmp(app.DS1000z.status,'open')
                % Set the device property. In this demo, the length of the input buffer is set to 2048.
                try
                    fopen(app.DS1000z);
                catch
                    error('Cannot open connection. Is the oscilloscope connected and switched on?')
                end
                if strcmp(app.DS1000z.status,'open')
                    app.connectedLamp.Color=[0, 1, 0];
                else
                    app.connectedLamp.Color=[1, 0, 0];
                end
            end
            
            % RAW Mode
            pause(0.01)
            fprintf(app.DS1000z, ':WAV:MODE RAW' );
            fprintf(app.DS1000z, ':WAV:MODE?' );
            disp(['Mode: ' fscanf(app.DS1000z)])
            
        end

        % Value changed function: NumberofChannelsEditField
        function NumberofChannelsEditFieldValueChanged(app, event)
            app.startStreamData=0;
            app.noChan = app.NumberofChannelsEditField.Value;

            drawnow();
            
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            try
                fclose(app.DS1000z);
            catch
            end
            delete(app)
            
        end

        % Button pushed function: startButton
        function startButtonPushed(app, event)
            app.startStreamData=1;
            app.oscData=[];
            app.oscDataLen=[];
            app.oscRAWData=[];
            app.oscRAWLen=[];
            app.oscDataVoltageScale=[];
            cla( app.UIAxes ,'reset');
            xlabel(app.UIAxes, 'Time in s')
            ylabel(app.UIAxes, 'Voltage in V')
            title(app.UIAxes, 'Oscilloscole Data')
            app.UIAxes.Box = 'on';
            app.UIAxes.XGrid = 'on';
            app.UIAxes.YGrid = 'on';
            app.UIAxes.ZGrid = 'on';
            drawnow();

            
            while app.startStreamData==1
                pause(0.1);
                app.getOscidata(app.noChan);
                
                app.oscDataTime=0:app.sampRate:(max(app.oscDataLen)-1)*app.sampRate;

                plot(app.UIAxes,app.oscDataTime,app.oscData(:,:))
                drawnow();
            end
        end

        % Button pushed function: stopButton
        function stopButtonPushed(app, event)
            app.startStreamData=0;
        end

        % Button pushed function: saveButton
        function saveButtonPushed(app, event)
            assignin('base','data',app.oscData)
            assignin('base','time',app.oscDataTime')
            assignin('base','Len',app.oscDataLen)
            assignin('base','sampRate',app.sampRate)
            
        end

        % Button pushed function: fftButton
        function fftButtonPushed(app, event)
            % FFT Analyse
            
            % Samplefrequenz
            Fs=1/mean(diff(app.oscDataTime));
            
            % Analyze the frequencies and amplitudes:
            %close all;
            NFFT = 2^nextpow2(length(app.oscData)); % Next power of 2 from length of y
            Y = fft(app.oscData,NFFT)/length(app.oscData);
            f = Fs*linspace(0,1,NFFT/2+1);
            % Plot single-sided amplitude spectrum.
            figure;
            plot(f,2*abs(Y(1:NFFT/2+1)),'linewidth',1);
            grid on;
            xlabel('Frequency (Hz)')
            ylabel('Amplitude')
            xlim([50 2000])
            
            % Lokale Maxima finden1-
            [pks,locs] = findpeaks(2*abs(Y(1:NFFT/2+1)),'MinPeakProminence',0.015,'MinPeakDistance',10);
            
            % Maxima markieren
            hold on;
            plot(f(locs),pks,'ro')
            TextStrukt={};
            for i=1:length(pks)
                TextStrukt{i}=[num2str(f(locs(i)),'%.1f') 'Hz'];
            end
            text(f(locs),pks*1.05,TextStrukt);
            
            % Reverse Engineering -  wie mache ich aus einem Ton ein Klang?
            frequenzen=f(locs(i));
            
            disp(['Abstand der Maximas:  ' num2str(diff(f(locs)),'%.1f ')])
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.ConnectButton = uibutton(app.LeftPanel, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectButtonPushed, true);
            app.ConnectButton.Text = 'Connect';
            app.connectedLampLabel = uilabel(app.LeftPanel);
            app.connectedLampLabel.Text = 'connected';
            app.connectedLamp = uilamp(app.LeftPanel);
            app.stopButton = uibutton(app.LeftPanel, 'push');
            app.stopButton.ButtonPushedFcn = createCallbackFcn(app, @stopButtonPushed, true);
            app.stopButton.Text = 'stop';
            app.NumberofChannelsLabel = uilabel(app.LeftPanel);
            app.NumberofChannelsLabel.Text = {'Number of '; 'Channels'};
            app.NumberofChannelsEditField = uieditfield(app.LeftPanel, 'numeric');
            app.NumberofChannelsEditField.Limits = [0 4];
            app.NumberofChannelsEditField.ValueChangedFcn = createCallbackFcn(app, @NumberofChannelsEditFieldValueChanged, true);
            app.NumberofChannelsEditField.Value = 1;
            app.startButton = uibutton(app.LeftPanel, 'push');
            app.startButton.ButtonPushedFcn = createCallbackFcn(app, @startButtonPushed, true);
            app.startButton.Text = 'start';
            app.saveButton = uibutton(app.LeftPanel, 'push');
            app.saveButton.ButtonPushedFcn = createCallbackFcn(app, @saveButtonPushed, true);
            app.saveButton.Text = 'save';
            app.exportdatatoworkspaceLabel = uilabel(app.LeftPanel);
            app.exportdatatoworkspaceLabel.Text = {'export data '; 'to workspace'};
            app.fftButton = uibutton(app.LeftPanel, 'push');
            app.fftButton.ButtonPushedFcn = createCallbackFcn(app, @fftButtonPushed, true);
            app.fftButton.Text = 'fft';
            app.FouriertransformationLabel = uilabel(app.LeftPanel);
            app.FouriertransformationLabel.Text = {'Fourier-'; 'transformation'};
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Oscilloscole Data')
            xlabel(app.UIAxes, 'Time in s')
            ylabel(app.UIAxes, 'Voltage in V')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = OszilloskopGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end